classdef DSP_PROJECT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UITable            matlab.ui.control.Table
        READTXTFILEButton  matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: READTXTFILEButton
        function READTXTFILEButtonPushed(app, event)

%READ TEXT FILE KNOP
%KIES EEN FILE IN DATA/PP01 FOLDER
%TOON FILE TITEL IN EDIT FIELD
%TOON FILE CONTENT IN TEXTAREA

[filename,path]=uigetfile("DATA/PP01/*.txt");
%app.OUTPUTEditField.Value = filename;
pathToFile = path + "/" + filename;
fileID = fopen(pathToFile);
loadedData = readmatrix(pathToFile);
%%app.TextArea.Value = A;
%%app.TextArea.FontSize = 20;
vars = {'nSeq','DI','CH1','CH2','CH3','CH4','CH5'};
app.UITable.ColumnName = vars;
app.UITable.Data = loadedData;
fclose(fileID);
figure(app.UIFigure);

%STAP 2
channelData = loadedData(:,3:end);
B = ((channelData ./ (2^16)) - (0.5)) * 3;
disp(B);


        end

        % Callback function
        function TextAreaValueChanged(app, event)
%             value = app.TextArea.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.READTXTFILEButton = uibutton(app.UIFigure, 'push');
            app.READTXTFILEButton.ButtonPushedFcn = createCallbackFcn(app, @READTXTFILEButtonPushed, true);
            app.READTXTFILEButton.Text = 'READ TXT FILE';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSP_PROJECT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end